classdef census < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        Panel                     matlab.ui.container.Panel
        loadButton                matlab.ui.control.Button
        clearButton               matlab.ui.control.Button
        saveButton                matlab.ui.control.Button
        exitButton                matlab.ui.control.Button
        TextAreaLabel             matlab.ui.control.Label
        TextArea                  matlab.ui.control.TextArea
        TabGroup                  matlab.ui.container.TabGroup
        PolyTab                   matlab.ui.container.Tab
        PolyButton                matlab.ui.control.Button
        Poly_OrderEditFieldLabel  matlab.ui.control.Label
        Poly_Order                matlab.ui.control.NumericEditField
        polyfitresults            matlab.ui.control.TextArea
        polyfitresultsLabel       matlab.ui.control.Label
        R2TextAreaLabel           matlab.ui.control.Label
        R2TextArea                matlab.ui.control.TextArea
        MSETextAreaLabel          matlab.ui.control.Label
        MSETextArea               matlab.ui.control.TextArea
        SSETextAreaLabel          matlab.ui.control.Label
        SSETextArea               matlab.ui.control.TextArea
        RMSETextAreaLabel         matlab.ui.control.Label
        RMSETextArea              matlab.ui.control.TextArea
        Find_yearTab              matlab.ui.container.Tab
        yLabel                    matlab.ui.control.Label
        yEditField                matlab.ui.control.NumericEditField
        TextArea2                 matlab.ui.control.TextArea
        FindButton                matlab.ui.control.Button
        popmioLabel               matlab.ui.control.Label
        Label                     matlab.ui.control.Label
    end

    
    properties (Access = public)
        cdatee
        popp
        figure
        mu
        ffigure
        cellArrayText = '';
        
        Property % Description
    end
    
    methods (Access = private)
        
        function func(app)
            load('census.mat','cdate','pop');
            app.cdatee = cdate;
            app.popp = pop;
            [~,~,app.mu] = polyfit(cdate,pop,0);
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            
            func(app);
            app.figure = plot(app.UIAxes,app.cdatee,app.popp,'r*');
            app.cellArrayText =strcat(app.cellArrayText,10,'加载成功');
            app.TextArea.Value = app.cellArrayText;
            
            
        end

        % Button pushed function: PolyButton
        function PolyButtonPushed(app, event)
            n = app.Poly_Order.Value;
            [~,~,pop_mu] = polyfit(app.popp,app.popp,0);
            [p,~,~] = polyfit(app.cdatee,app.popp,n);
            pop_fit = polyval(p,app.cdatee,[],app.mu);
            delete(app.ffigure);
            hold(app.UIAxes,'on');
            
            app.ffigure = plot(app.UIAxes,app.cdatee,pop_fit,'b-');
            
            hold(app.UIAxes,'off');
            
            t = (pop_fit-app.popp).^2;
            
            sse = sum(t);
            app.SSETextArea.Value = num2str(sse);
            
            mean1 = ones(length(app.popp),1)*pop_mu(1);
            sst = sum((mean1-app.popp).^2);
            app.R2TextArea.Value = sprintf('%.4f',1 - sse/sst);
            
            
            mse1 = mse(pop_fit-app.popp);
            app.MSETextArea.Value = sprintf('%.4f',mse1);
            rmse = sqrt(mse1);
            app.RMSETextArea.Value = sprintf('%.4f',rmse);


            st = '';
            t = n;
            for i = 1:(n+1)
                if t~=0
                    str =strcat(num2str(p(i),4),'*x^',num2str(t,4));
                    t = t-1;
                else
                    str =num2str(p(i),4);
                end
                if i == 1
                    st = strcat(str);
                else
                    st = strcat(st,' + ',str);
                end
            end
            
            st = strcat('f(x) = ',st);
            str = strcat(num2str(n),'阶多项式');
            app.polyfitresults.Value = st;
            app.cellArrayText = strcat(app.cellArrayText,10,str,'拟合成功');
            app.TextArea.Value = app.cellArrayText;
            
            
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            delete(app.figure);
            delete(app.ffigure);
            app.Poly_Order.Value = 0;
            
            app.R2TextArea.Value = '';
            app.MSETextArea.Value = '';
            app.SSETextArea.Value = '';
            app.RMSETextArea.Value = '';
            
            app.TextArea.Value = 'output results';
            app.polyfitresults.Value = '';
            
            app.cellArrayText ='清除成功';
            app.TextArea.Value = app.cellArrayText;
        end

        % Button pushed function: FindButton
        function FindButtonPushed(app, event)
            year = app.yEditField.Value;
            app.TextArea2.Value = '';
            flag = 1;
            for i = 1:21
                if (app.cdatee(i) == year)
                    app.TextArea2.Value = sprintf('%.2f',app.popp(i));
                    flag = 0;
                end
            end
            
            if(flag == 1)
                app.cellArrayText =strcat(app.cellArrayText,10,'没有该年份数据');
            else
                app.cellArrayText =strcat(app.cellArrayText,10,'查询成功');
            end
            
            app.TextArea.Value = app.cellArrayText;


        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            % t = [cdate,pop]
            cdate = app.cdatee;
            pop = app.popp;
            save ('popdata.mat','cdate','pop');
            app.cellArrayText =strcat(app.cellArrayText,10,'保存成功');
            app.TextArea.Value = app.cellArrayText;
        end

        % Button pushed function: exitButton
        function exitButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure,'Close APP?','Quit', 'Icon','warning');
            switch selection
                case 'OK'
                    app.delete();
                case 'Cancel'
                    return
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'U.S. Population from 1790 to 1990')
            xlabel(app.UIAxes, 'Census   year')
            ylabel(app.UIAxes, 'Population(millions)')
            app.UIAxes.XLim = [1750 2000];
            app.UIAxes.YLim = [0 300];
            app.UIAxes.XTickLabel = {'1750'; '1800'; '1850'; '1900'; '1950'; '2000'};
            app.Panel = uipanel(app.UIFigure);
            app.loadButton = uibutton(app.Panel, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.clearButton = uibutton(app.Panel, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.saveButton = uibutton(app.Panel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.exitButton = uibutton(app.Panel, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exitButton.Text = 'exit';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'output results'; ''};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PolyTab = uitab(app.TabGroup);
            app.PolyTab.Title = 'Poly';
            app.PolyButton = uibutton(app.PolyTab, 'push');
            app.PolyButton.ButtonPushedFcn = createCallbackFcn(app, @PolyButtonPushed, true);
            app.PolyButton.Text = 'Poly';
            app.Poly_OrderEditFieldLabel = uilabel(app.PolyTab);
            app.Poly_OrderEditFieldLabel.Text = 'Poly_Order';
            app.Poly_Order = uieditfield(app.PolyTab, 'numeric');
            app.polyfitresults = uitextarea(app.PolyTab);
            app.polyfitresults.Editable = 'off';
            app.polyfitresultsLabel = uilabel(app.PolyTab);
            app.polyfitresultsLabel.Text = 'polyfit results';
            app.R2TextAreaLabel = uilabel(app.PolyTab);
            app.R2TextAreaLabel.Text = 'R^2';
            app.R2TextArea = uitextarea(app.PolyTab);
            app.R2TextArea.Editable = 'off';
            app.R2TextArea.Value = {'0'};
            app.MSETextAreaLabel = uilabel(app.PolyTab);
            app.MSETextAreaLabel.Text = 'MSE';
            app.MSETextArea = uitextarea(app.PolyTab);
            app.MSETextArea.Editable = 'off';
            app.MSETextArea.Value = {'0'};
            app.SSETextAreaLabel = uilabel(app.PolyTab);
            app.SSETextAreaLabel.Text = 'SSE';
            app.SSETextArea = uitextarea(app.PolyTab);
            app.SSETextArea.Editable = 'off';
            app.SSETextArea.Value = {'0'};
            app.RMSETextAreaLabel = uilabel(app.PolyTab);
            app.RMSETextAreaLabel.Text = 'RMSE';
            app.RMSETextArea = uitextarea(app.PolyTab);
            app.RMSETextArea.Editable = 'off';
            app.RMSETextArea.Value = {'0'};
            app.Find_yearTab = uitab(app.TabGroup);
            app.Find_yearTab.Title = 'Find_year';
            app.yLabel = uilabel(app.Find_yearTab);
            app.yLabel.Text = '1790~1990y';
            app.yEditField = uieditfield(app.Find_yearTab, 'numeric');
            app.TextArea2 = uitextarea(app.Find_yearTab);
            app.FindButton = uibutton(app.Find_yearTab, 'push');
            app.FindButton.ButtonPushedFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.FindButton.Text = 'Find';
            app.popmioLabel = uilabel(app.Find_yearTab);
            app.popmioLabel.Text = 'pop(mio)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '人口普查数据多项式拟合系统';
        end
    end

    methods (Access = public)

        % Construct app
        function app = census

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end