classdef xiaoshou < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        RunPanel                matlab.ui.container.Panel
        plotButton              matlab.ui.control.Button
        saveButton              matlab.ui.control.Button
        clearButton             matlab.ui.control.Button
        exitButton              matlab.ui.control.Button
        Plan_SalesPanel         matlab.ui.container.Panel
        Label_2                 matlab.ui.control.Label
        sales_1                 matlab.ui.control.NumericEditField
        Label_3                 matlab.ui.control.Label
        sales_2                 matlab.ui.control.NumericEditField
        Label_6                 matlab.ui.control.Label
        sales_5                 matlab.ui.control.NumericEditField
        Label_7                 matlab.ui.control.Label
        sales_6                 matlab.ui.control.NumericEditField
        Label_10                matlab.ui.control.Label
        sales_9                 matlab.ui.control.NumericEditField
        Label_11                matlab.ui.control.Label
        sales_10                matlab.ui.control.NumericEditField
        Label_8                 matlab.ui.control.Label
        sales_7                 matlab.ui.control.NumericEditField
        Label_9                 matlab.ui.control.Label
        sales_8                 matlab.ui.control.NumericEditField
        Label_4                 matlab.ui.control.Label
        sales_3                 matlab.ui.control.NumericEditField
        Label_5                 matlab.ui.control.Label
        sales_4                 matlab.ui.control.NumericEditField
        TextAreaLabel           matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        StartProEditFieldLabel  matlab.ui.control.Label
        StartProEditField       matlab.ui.control.NumericEditField
        StrarStoEditFieldLabel  matlab.ui.control.Label
        StrarStoEditField       matlab.ui.control.NumericEditField
        Label_12                matlab.ui.control.Label
    end

    
    properties (Access = private)
        start_producty;
        start_storey;
        Plan_sales = ones(10,1);
        stores = ones(10,1);
        produce = ones(10,1);
        week = [1;2;3;4;5;6;7;8;9;10];
        figure;
        cellArrayText = '';
        % Description
    end
    

    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            app.Plan_sales(1) = app.sales_1.Value;
            app.Plan_sales(2) = app.sales_2.Value;
            app.Plan_sales(3) = app.sales_3.Value;
            app.Plan_sales(4) = app.sales_4.Value;
            app.Plan_sales(5) = app.sales_5.Value;
            app.Plan_sales(6) = app.sales_6.Value;
            app.Plan_sales(7) = app.sales_7.Value;
            app.Plan_sales(8) = app.sales_8.Value;
            app.Plan_sales(9) = app.sales_9.Value;
            app.Plan_sales(10) = app.sales_10.Value;
            
            %plot(app.UIAxes,app.start_producty,'o');
            
            app.produce(1) = app.StartProEditField.Value;
            app.stores(1) = app.StrarStoEditField.Value;
            flag= 1;
            
            t = 100;
            for i=1:10
                if i>1
                    app.produce(i)=app.Plan_sales(i-1);
                    app.stores(i) = app.stores(i-1) + app.produce(i-1) - app.Plan_sales(i-1);
                    if app.stores(i) < 0
                        app.stores(i) = 0;
                    end
                end
                if (app.stores(i)==0)&&(flag== 1)
                    t = i;
                    flag = 0;
                end
            end


            if (t~=100)
                text = sprintf('%s%d%s','第',t,'周库存减少到0');
            else
                text = sprintf('%s','10周内库存没有到0');
            end
            app.cellArrayText = strcat(app.cellArrayText,10,'拟合成功');
            
            app.cellArrayText = strcat(app.cellArrayText,10,text);
            
            app.TextArea.Value = app.cellArrayText;
            
            app.figure = plot(app.UIAxes,app.week,app.stores);
            
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            sales = app.Plan_sales;
            save ('xs.mat','sales');
            
            app.cellArrayText = strcat(app.cellArrayText,10,'保存成功');
            app.TextArea.Value = app.cellArrayText;
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            app.cellArrayText = '';
            app.TextArea.Value = '清除成功';
            
            delete(app.figure);
            
        end

        % Button pushed function: exitButton
        function exitButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure,'Close APP?','Quit', 'Icon','warning');
            switch selection
                case 'OK'
                    app.delete();
                case 'Cancel'
                    return
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '10周内高尔夫手推车库存数量变化')
            xlabel(app.UIAxes, '时间')
            ylabel(app.UIAxes, '库存量')
            app.RunPanel = uipanel(app.UIFigure);
            app.RunPanel.Title = 'Run';
            app.plotButton = uibutton(app.RunPanel, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.saveButton = uibutton(app.RunPanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.clearButton = uibutton(app.RunPanel, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.exitButton = uibutton(app.RunPanel, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exitButton.Text = 'exit';
            app.Plan_SalesPanel = uipanel(app.UIFigure);
            app.Plan_SalesPanel.Title = 'Plan_Sales';
            app.Label_2 = uilabel(app.Plan_SalesPanel);
            app.Label_2.Text = '第一周';
            app.sales_1 = uieditfield(app.Plan_SalesPanel, 'numeric');
            app.sales_1.Value = 50;
            app.Label_3 = uilabel(app.Plan_SalesPanel);
            app.Label_3.Text = '第二周';
            app.sales_2 = uieditfield(app.Plan_SalesPanel, 'numeric');
            app.sales_2.Value = 55;
            app.Label_6 = uilabel(app.Plan_SalesPanel);
            app.Label_6.Text = '第五周';
            app.sales_5 = uieditfield(app.Plan_SalesPanel, 'numeric');
            app.sales_5.Value = 70;
            app.Label_7 = uilabel(app.Plan_SalesPanel);
            app.Label_7.Text = '第六周';
            app.sales_6 = uieditfield(app.Plan_SalesPanel, 'numeric');
            app.sales_6.Value = 75;
            app.Label_10 = uilabel(app.Plan_SalesPanel);
            app.Label_10.Text = '第九周';
            app.sales_9 = uieditfield(app.Plan_SalesPanel, 'numeric');
            app.sales_9.Value = 90;
            app.Label_11 = uilabel(app.Plan_SalesPanel);
            app.Label_11.Text = '第十周';
            app.sales_10 = uieditfield(app.Plan_SalesPanel, 'numeric');
            app.sales_10.Value = 55;
            app.Label_8 = uilabel(app.Plan_SalesPanel);
            app.Label_8.Text = '第七周';
            app.sales_7 = uieditfield(app.Plan_SalesPanel, 'numeric');
            app.sales_7.Value = 80;
            app.Label_9 = uilabel(app.Plan_SalesPanel);
            app.Label_9.Text = '第八周';
            app.sales_8 = uieditfield(app.Plan_SalesPanel, 'numeric');
            app.sales_8.Value = 80;
            app.Label_4 = uilabel(app.Plan_SalesPanel);
            app.Label_4.Text = '第三周';
            app.sales_3 = uieditfield(app.Plan_SalesPanel, 'numeric');
            app.sales_3.Value = 60;
            app.Label_5 = uilabel(app.Plan_SalesPanel);
            app.Label_5.Text = '第四周';
            app.sales_4 = uieditfield(app.Plan_SalesPanel, 'numeric');
            app.sales_4.Value = 70;
            app.TextAreaLabel = uilabel(app.Plan_SalesPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.Plan_SalesPanel);
            app.TextArea.Value = {'output results'};
            app.StartProEditFieldLabel = uilabel(app.UIFigure);
            app.StartProEditFieldLabel.Text = 'StartPro';
            app.StartProEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartProEditField.Value = 50;
            app.StrarStoEditFieldLabel = uilabel(app.UIFigure);
            app.StrarStoEditFieldLabel.Text = 'StrarSto';
            app.StrarStoEditField = uieditfield(app.UIFigure, 'numeric');
            app.StrarStoEditField.Value = 50;
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '高尔夫球手推车销售系统';
        end
    end

    methods (Access = public)

        % Construct app
        function app = xiaoshou

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end